classdef InvIPMtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultadosPanel                 matlab.ui.container.Panel
        UIAxes_invariante_segmentada    matlab.ui.control.UIAxes
        UIAxes_invariante_resultado     matlab.ui.control.UIAxes
        UIAxes_original_segmentada      matlab.ui.control.UIAxes
        UIAxes_original_resultado       matlab.ui.control.UIAxes
        InvariantedeIluminacinsobrePiezasMetlicastestLabel  matlab.ui.control.Label
        GuardarresultadosButton         matlab.ui.control.Button
        EjecutarButton                  matlab.ui.control.Button
        NmerodecentrosEditField         matlab.ui.control.NumericEditField
        NmerodecentrosEditFieldLabel    matlab.ui.control.Label
        MtodoinvarianteButtonGroup      matlab.ui.container.ButtonGroup
        PCAButton                       matlab.ui.control.RadioButton
        MaddLightNavButton              matlab.ui.control.RadioButton
        UpcrofButton                    matlab.ui.control.RadioButton
        KrajnkButton                    matlab.ui.control.RadioButton
        MaddernButton                   matlab.ui.control.RadioButton
        AlvarezButton                   matlab.ui.control.RadioButton
        MtododeagrupamientoButtonGroup  matlab.ui.container.ButtonGroup
        InformacinespacialButton        matlab.ui.control.RadioButton
        GaussianMixturesButton          matlab.ui.control.RadioButton
        FuzzyCMeansButton               matlab.ui.control.RadioButton
        KMeansButton                    matlab.ui.control.RadioButton
        CargarimagenButton              matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UIAxes_ground_truth             matlab.ui.control.UIAxes
        UIAxes_original                 matlab.ui.control.UIAxes
    end

    % Funciones auxiliares
    properties (Access = public)
        imagen
        imagen_ground_truth
        imagen_tres_canales
        nombre_imagen
        extension
        tipo_agrup
        numClusters
        tipo
        tipo2
        imagen_original
        imagen_original_agrup
        imagen_invariante
        imagen_invariante_agrup
        porcentaje_original
        porcentaje_invariante
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Cargo las funciones
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            functionsPath = fullfile(raiz, 'src', 'functions');
            addpath(functionsPath);

            % Oculto las graficas donde mostrare las imagenes solución
            app.UIAxes_original.Visible = "off";
            app.UIAxes_ground_truth.Visible = "off";
            app.UIAxes_original_resultado.Visible = "off";
            app.UIAxes_original_segmentada.Visible = "off";
            app.UIAxes_invariante_resultado.Visible = "off";
            app.UIAxes_invariante_segmentada.Visible = "off";

            % Inicialmente ni se puede ejecutar ni guardar
            app.EjecutarButton.Enable = 'off';
            app.GuardarresultadosButton.Enable = 'off';
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
            % Abro un cuadro de diálogo para seleccionar una imagen.
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            directorio_origen = fullfile(raiz, 'data', 'Imagenes');
            directorio_ground_truth = fullfile(raiz, 'data', 'Imagenes_ground_truth');
            [imagen_seleccionada, ruta] = uigetfile(fullfile(directorio_origen, '*.*'), 'Selecciona una imagen');
            
            % Por si se cancela el cuadro de diálogo.
            if isequal(imagen_seleccionada, 0)
                disp('Selección cancelada');
                return;
            else
                % Extraigo el nombre y la extensión de la imagen seleccionada.
                [~, app.nombre_imagen, app.extension] = fileparts(imagen_seleccionada);
                
                % Leo la imagen desde su ruta completa.
                app.imagen = imread(fullfile(ruta, imagen_seleccionada));
                app.imagen_original = app.imagen;
                
                % Obtengo la ruta de la imagen ground truth en base a la
                % seleccionada por el usuario
                posicion_inicio = strfind(ruta, directorio_origen);
                posicion_inicio = posicion_inicio + length(directorio_origen);
                ruta_intermedia = ruta(posicion_inicio:end);
                ruta_ground_truth = fullfile(directorio_ground_truth, ruta_intermedia);
                archivo_ground_truth = fullfile(ruta_ground_truth, [app.nombre_imagen, app.extension]);

                % Compruebo si existe la imagen ground truth
                if isfile(archivo_ground_truth)
                    % Leo la imagen ground truth
                    app.imagen_ground_truth = imread(archivo_ground_truth);
            
                    % Muestro las imagenes donde corresponde
                    app.Image.Visible = "off";
                    app.Image_2.Visible = "off";
                    imshow(app.imagen,'Parent',app.UIAxes_original);
                    imshow(app.imagen_ground_truth,'Parent',app.UIAxes_ground_truth);
                    app.CargarimagenButton.Text = 'Cargar otra imagen';

                    % Al ya haber una imagen, habilito el boton de ejecutar
                    app.EjecutarButton.Enable = 'on';
                else
                    app.CargarimagenButton.Text = 'Carga una imagen de data/';
                end

            end
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            % Oculto la ejecución anterior (TODO)
            cla(app.UIAxes_original_resultado);
            cla(app.UIAxes_original_segmentada);
            cla(app.UIAxes_invariante_resultado);
            cla(app.UIAxes_invariante_segmentada);
            app.EjecutarButton.Text = 'Cargando...';
            app.GuardarresultadosButton.Enable = 'on';

            % Obtengo la imagen de tres canales
            app.imagen_tres_canales = ImagenTresCanales(app.imagen);

            % Calculo el invariante
            if app.AlvarezButton.Value == 1
                app.tipo = 'Alvarez';
                %[app.tipo2, aux] = aux_propio(1, .5);
                app.tipo2 = num2str(.5); aux = .5;
                app.imagen_invariante = metodos_invariantes_app(1, app.imagen_tres_canales, aux);
            elseif app.MaddernButton.Value == 1
                % Gran cantidad de ruido
                app.tipo = 'Maddern';
                %[app.tipo2, aux] = aux_propio(1, .48);
                app.tipo2 = num2str(.5); aux = .48;
                app.imagen_invariante = metodos_invariantes_app(2, app.imagen_tres_canales, aux);
            elseif app.KrajnkButton.Value == 1
                app.tipo = 'Krajnık';
                % de 0 a 180º y no hace falta truncar
                %[app.tipo2, aux] = aux_propio(1, 45);
                app.tipo2 = num2str(.5); aux = 45;
                app.imagen_invariante = metodos_invariantes_app(3, app.imagen_tres_canales, aux);
            elseif app.UpcrofButton.Value == 1
                app.tipo = 'Upcrof';
                % Depende mucho de la camara, con .9 se ve bien, con 2 muy bien 
                %[app.tipo2, aux] = aux_propio(1, 2);
                app.tipo2 = num2str(.5); aux = 2;
                app.imagen_invariante = metodos_invariantes_app(4, app.imagen_tres_canales, aux);
            elseif app.MaddLightNavButton.Value == 1
                % Will Maddern, Alexander D. Stewart, Colin McManus, Ben Upcroft, Winston Churchill, y Paul Newman
                app.tipo = 'MaddLightNav';
                %[app.tipo2, aux] = aux_propio(1, .2);
                app.tipo2 = num2str(.5); aux = .2;
                app.imagen_invariante = metodos_invariantes_app(5, app.imagen_tres_canales, aux);
            elseif app.PCAButton.Value == 1
                app.tipo = 'PCA';
                app.tipo2 = ''; aux = 0;
                app.imagen_invariante = metodos_invariantes_app(6, app.imagen_tres_canales, aux);
            end

            % Calculo la segmentación
            app.numClusters = app.NmerodecentrosEditField.Value;
            imagen_invariante_tres = cat(3, app.imagen_invariante, app.imagen_invariante, app.imagen_invariante);

            if app.KMeansButton.Value == 1
                app.tipo_agrup = 'K-Means';
                [app.imagen_original_agrup, ~] = segmentar_imagen_KMeans(app.imagen_tres_canales, app.numClusters);
                [app.imagen_invariante_agrup, ~] = segmentar_imagen_KMeans(imagen_invariante_tres, app.numClusters);
            elseif app.FuzzyCMeansButton.Value == 1
                app.tipo_agrup = 'C-Means';
                [app.imagen_original_agrup, ~] = segmentar_imagen_fuzzy_CMeans(app.imagen_tres_canales, app.numClusters);
                [app.imagen_invariante_agrup, ~] = segmentar_imagen_fuzzy_CMeans(imagen_invariante_tres, app.numClusters);
            elseif app.GaussianMixturesButton.Value == 1
                app.tipo_agrup = 'GMM';
                [app.imagen_original_agrup, ~] = segmentar_imagen_GMM(app.imagen_tres_canales, app.numClusters);
                [app.imagen_invariante_agrup, ~] = segmentar_imagen_GMM(imagen_invariante_tres, app.numClusters);
            elseif app.InformacinespacialButton.Value == 1
                app.tipo_agrup = 'Spatial';
                % TODO
            end

            app.porcentaje_original = calcular_coincidencia(app.imagen_original_agrup, app.imagen_ground_truth);
            app.porcentaje_invariante = calcular_coincidencia(app.imagen_invariante_agrup, app.imagen_ground_truth);

            % Muestro las imágenes
            imshow(app.imagen_original, 'Parent', app.UIAxes_original_resultado);
            imshow(app.imagen_original_agrup, 'Parent', app.UIAxes_original_segmentada);
            imshow(app.imagen_invariante, 'Parent', app.UIAxes_invariante_resultado);
            imshow(app.imagen_invariante_agrup, 'Parent', app.UIAxes_invariante_segmentada);
            app.UIAxes_original_segmentada.Title.String = ['Original segmentada - ' num2str(app.porcentaje_original) '%'];
            app.UIAxes_invariante_segmentada.Title.String = ['Invariante segmentada - ' num2str(app.porcentaje_invariante) '%'];
            app.EjecutarButton.Text = 'Ejecutar de nuevo';

            % Al ya haber resultados, habilito el boton de guardar
            app.GuardarresultadosButton.Enable = 'on';
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            % Define una ubicación por defecto
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            ubicacion_por_defecto = fullfile(raiz, 'results', 'AppTest');
            
            % Abre un cuadro de diálogo para que el usuario elija una ubicación
            ubicacion = uigetdir(ubicacion_por_defecto, 'Seleccione una ubicación para guardar las imágenes');
            
            % Si el usuario presiona cancelar, uigetdir retorna 0
            if ubicacion == 0
                ubicacion = ubicacion_por_defecto;
            end
            
            % Guarda las imágenes
            imwrite(app.imagen_original, fullfile(ubicacion, [app.nombre_imagen app.extension]));
            imwrite(app.imagen_original_agrup, fullfile(ubicacion, [app.nombre_imagen '_Original_' num2str(app.porcentaje_original) '%_' app.tipo_agrup '_' num2str(app.numClusters) 'c.jpg']));
            imwrite(app.imagen_invariante, fullfile(ubicacion, [app.nombre_imagen '_' app.tipo app.tipo2 app.extension]));
            imwrite(app.imagen_invariante_agrup, fullfile(ubicacion, [app.nombre_imagen '_' app.tipo '_' num2str(app.porcentaje_invariante) '%_' app.tipo_agrup '_' num2str(app.numClusters) 'c.jpg']));
            
            % Cambia el estado y texto del botón
            app.GuardarresultadosButton.Enable = 'off';
            app.GuardarresultadosButton.Text = 'Imágenes Guardadas';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_original = uiaxes(app.UIFigure);
            title(app.UIAxes_original, 'Imagen original')
            app.UIAxes_original.XTick = [];
            app.UIAxes_original.XTickLabel = '';
            app.UIAxes_original.YTick = [];
            app.UIAxes_original.YTickLabel = '';
            app.UIAxes_ground_truth = uiaxes(app.UIFigure);
            title(app.UIAxes_ground_truth, 'Imagen ground truth')
            app.UIAxes_ground_truth.XTick = [];
            app.UIAxes_ground_truth.XTickLabel = '';
            app.UIAxes_ground_truth.YTick = [];
            app.UIAxes_ground_truth.YTickLabel = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Enable = 'off';
            app.CargarimagenButton = uibutton(app.UIFigure, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.MtododeagrupamientoButtonGroup = uibuttongroup(app.UIFigure);
            app.MtododeagrupamientoButtonGroup.Title = 'Método de agrupamiento';
            app.KMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.KMeansButton.Text = 'K-Means';
            app.KMeansButton.Value = true;
            app.FuzzyCMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.FuzzyCMeansButton.Text = 'Fuzzy C-Means';
            app.GaussianMixturesButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.GaussianMixturesButton.Text = 'Gaussian Mixtures';
            app.InformacinespacialButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.InformacinespacialButton.Text = 'Información espacial';
            app.MtodoinvarianteButtonGroup = uibuttongroup(app.UIFigure);
            app.MtodoinvarianteButtonGroup.Title = 'Método invariante';
            app.AlvarezButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.AlvarezButton.Text = 'Alvarez';
            app.AlvarezButton.Value = true;
            app.MaddernButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddernButton.Text = 'Maddern';
            app.KrajnkButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.KrajnkButton.Text = 'Krajnık';
            app.UpcrofButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.UpcrofButton.Text = 'Upcrof';
            app.MaddLightNavButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddLightNavButton.Text = 'MaddLightNav';
            app.PCAButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.NmerodecentrosEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodecentrosEditFieldLabel.Text = 'Número de centros';
            app.NmerodecentrosEditField = uieditfield(app.UIFigure, 'numeric');
            app.NmerodecentrosEditField.Limits = [2 Inf];
            app.NmerodecentrosEditField.RoundFractionalValues = 'on';
            app.NmerodecentrosEditField.Value = 2;
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.GuardarresultadosButton = uibutton(app.UIFigure, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.InvariantedeIluminacinsobrePiezasMetlicastestLabel = uilabel(app.UIFigure);
            app.InvariantedeIluminacinsobrePiezasMetlicastestLabel.Text = 'Invariante de Iluminación sobre Piezas Metálicas (test)';
            app.ResultadosPanel = uipanel(app.UIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.UIAxes_original_resultado = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_original_resultado, 'Imagen original')
            app.UIAxes_original_resultado.XTick = [];
            app.UIAxes_original_resultado.YTick = [];
            app.UIAxes_original_segmentada = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_original_segmentada, 'Original segmentada')
            zlabel(app.UIAxes_original_segmentada, 'Z')
            app.UIAxes_original_segmentada.XTick = [];
            app.UIAxes_original_segmentada.YTick = [];
            app.UIAxes_invariante_resultado = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_invariante_resultado, 'Imagen invariante')
            zlabel(app.UIAxes_invariante_resultado, 'Z')
            app.UIAxes_invariante_resultado.XTick = [];
            app.UIAxes_invariante_resultado.YTick = [];
            app.UIAxes_invariante_segmentada = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_invariante_segmentada, 'Invariante segmentada')
            zlabel(app.UIAxes_invariante_segmentada, 'Z')
            app.UIAxes_invariante_segmentada.XTick = [];
            app.UIAxes_invariante_segmentada.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InvIPMtest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end